classdef SCADA2Tanques < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup2                      matlab.ui.container.TabGroup
        Anlisis1Tab                    matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        H1rTab                         matlab.ui.container.Tab
        H1r                            matlab.ui.control.UIAxes
        H2rTab                         matlab.ui.container.Tab
        H2r                            matlab.ui.control.UIAxes
        H1eTab                         matlab.ui.container.Tab
        H1e                            matlab.ui.control.UIAxes
        H2eTab                         matlab.ui.container.Tab
        H2e                            matlab.ui.control.UIAxes
        EventosTextAreaLabel           matlab.ui.control.Label
        Status1                        matlab.ui.control.TextArea
        INICIOButton                   matlab.ui.control.Button
        Anlisis2Tab                    matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        Tanque1Tab                     matlab.ui.container.Tab
        Entrop                         matlab.ui.control.UIAxes
        TipodegrficaButtonGroupRe      matlab.ui.container.ButtonGroup
        EntropaButton                  matlab.ui.control.RadioButton
        HrvsHeButton                   matlab.ui.control.RadioButton
        HistogramaButton               matlab.ui.control.RadioButton
        EventosTextAreaLabel_2         matlab.ui.control.Label
        Eventos                        matlab.ui.control.TextArea
        Tanque2Tab                     matlab.ui.container.Tab
        Entrop_2                       matlab.ui.control.UIAxes
        TipodegrficaButtonGroupRe_2    matlab.ui.container.ButtonGroup
        Entropa2Button                 matlab.ui.control.RadioButton
        HrvsHe2Button                  matlab.ui.control.RadioButton
        Histograma2Button              matlab.ui.control.RadioButton
        EventosTextAreaLabel_3         matlab.ui.control.Label
        Eventos_2                      matlab.ui.control.TextArea
        InicioButton                   matlab.ui.control.Button
        PerturbacindenivelEditFieldLabel  matlab.ui.control.Label
        perturbaE                      matlab.ui.control.NumericEditField
        ErrorentuberaEditFieldLabel    matlab.ui.control.Label
        ErrorTub                       matlab.ui.control.NumericEditField
        Anlisis3Tab                    matlab.ui.container.Tab
        ErrorentuberaEditFieldLabel_2  matlab.ui.control.Label
        ErrorTub2                      matlab.ui.control.NumericEditField
        PerturbacindenivelEditFieldLabel_2  matlab.ui.control.Label
        perturbaE2                     matlab.ui.control.NumericEditField
        NivelmedidoLabel               matlab.ui.control.Label
        NivelmedidoLabel_2             matlab.ui.control.Label
        NivelestimadoLabel             matlab.ui.control.Label
        NivelestimadoLabel_2           matlab.ui.control.Label
        nm1                            matlab.ui.control.NumericEditField
        ne1                            matlab.ui.control.NumericEditField
        nm2                            matlab.ui.control.NumericEditField
        ne2                            matlab.ui.control.NumericEditField
        FalladesensordenivelLampLabel  matlab.ui.control.Label
        FalladesensordenivelLamp       matlab.ui.control.Lamp
        FalladetuberiaLampLabel        matlab.ui.control.Label
        FalladetuberiaLamp             matlab.ui.control.Lamp
        InicioButton3                  matlab.ui.control.Button
        modeladoTab                    matlab.ui.container.Tab
        inicioButton4                  matlab.ui.control.Button
        TabGroup4                      matlab.ui.container.TabGroup
        sensoraltura1Tab               matlab.ui.container.Tab
        s1                             matlab.ui.control.UIAxes
        s2                             matlab.ui.control.UIAxes
        sensoraltura2Tab               matlab.ui.container.Tab
        s3                             matlab.ui.control.UIAxes
        s4                             matlab.ui.control.UIAxes
        EntrenamientoTab               matlab.ui.container.Tab
        TabGroup5                      matlab.ui.container.TabGroup
        fallasensordenivel1Tab         matlab.ui.container.Tab
        Entrenar                       matlab.ui.control.Button
        SelecciondeperturbacionButtonGroup  matlab.ui.container.ButtonGroup
        Error_5                        matlab.ui.control.RadioButton
        Error_10                       matlab.ui.control.RadioButton
        Error_15                       matlab.ui.control.RadioButton
        Discriminant1                  matlab.ui.control.UIAxes
        fallasensornivel2Tab           matlab.ui.container.Tab
        Entrenar2                      matlab.ui.control.Button
        Selecciondeperturbacion2ButtonGroup  matlab.ui.container.ButtonGroup
        Error2_1                       matlab.ui.control.RadioButton
        Error2_3                       matlab.ui.control.RadioButton
        Error2_5                       matlab.ui.control.RadioButton
        Discriminant2                  matlab.ui.control.UIAxes
        PerturbaciondetuberiaTab       matlab.ui.container.Tab
        Entrenar3                      matlab.ui.control.Button
        Selecciondeperturbacion3ButtonGroup  matlab.ui.container.ButtonGroup
        Error3_1N                      matlab.ui.control.RadioButton
        Error3_3N                      matlab.ui.control.RadioButton
        Error3_5N                      matlab.ui.control.RadioButton
        Discriminant3                  matlab.ui.control.UIAxes
        DeteccindefallasTab            matlab.ui.container.Tab
        Detectar                       matlab.ui.control.Button
        Selecciondeperturbacion4ButtonGroup  matlab.ui.container.ButtonGroup
        Fallasensor1                   matlab.ui.control.RadioButton
        Fallasensor2                   matlab.ui.control.RadioButton
        Fallasensor3                   matlab.ui.control.RadioButton
        ValorfallaLabel                matlab.ui.control.Label
        get_Valor                      matlab.ui.control.NumericEditField
        EventosTextAreaLabel_4         matlab.ui.control.Label
        EventosD                       matlab.ui.control.TextArea
        Sensor1Label                   matlab.ui.control.Label
        Sensor1                        matlab.ui.control.Lamp
        Sensor2Label                   matlab.ui.control.Label
        Sensor2                        matlab.ui.control.Lamp
        Sensor3Label                   matlab.ui.control.Label
        Sensor3                        matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: INICIOButton
        function INICIOButtonPushed(app, event)
           %A1=95.5200;
           %A2=64;
           %k1=0.95800;
           %k2=1.363500;
        
           
         data1=["Cargando parámetros..."];
         app.Status1.Value=data1;
             
         assignin('base','A1',95.52);
         assignin('base','A2',64);
         assignin('base','k1',0.958);
         assignin('base','k2',1.3635);
         
         assignin('base','perturbacion',0);
         assignin('base','perturbacion2',0);
         assignin('base','errortub',0);
         
         
         data1=[data1 "Iniciando simulación..."];
         app.Status1.Value=data1;
          
         simout=sim('Analisis_dos_tanques.slx','StopTime',num2str(8000));
         
         plot(app.H1r, simout.NivelM1.Time, simout.NivelM1.Data);
         plot(app.H2r, simout.NivelM2.Time, simout.NivelM2.Data);
        
         plot(app.H1e, simout.NivelE1.Time, simout.NivelE1.Data);
         plot(app.H2e, simout.NivelE2.Time, simout.NivelE2.Data);
         
         data1=[data1 "Simulación terminada."];
         app.Status1.Value=data1;
         
        
        end

        % Value changed function: Status1
        function Status1ValueChanged(app, event)
            value = app.Status1.Value;
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
         data1=["Cargando parámetros..."];
         app.Eventos.Value=data1;
         app.Eventos_2.Value=data1;
         assignin('base','A1',95.52);
         assignin('base','A2',64);
         assignin('base','k1',0.958);
         assignin('base','k2',1.3635);
  
         assignin('base','perturbacion',app.perturbaE.Value);
         assignin('base','perturbacion2',app.perturbaE.Value);
         assignin('base','errortub',app.ErrorTub.Value);
         
         data1=[data1 "Iniciando simulación..."];
         app.Eventos.Value=data1;
         app.Eventos_2.Value=data1;
         simout=sim('Analisis_dos_tanques.slx','StopTime',num2str(8000));


         EnH1r=pentropy(simout.NivelM1.Data,10000);

         Nm1=simout.NivelM1.Data;
         Ne1=simout.NivelE1.Data;
         
         L = length(EnH1r);
         t=(0:1:L-1)*(8000/L-1);
            
          %%%%%%%%%%%%%%%%%Tanque 2
          EnH2r=pentropy(simout.NivelM2.Data,10000);

            Nm2=simout.NivelM2.Data;
            Ne2=simout.NivelE2.Data;
            L2 = length(EnH2r);
            t2=(0:1:L2-1)*(8000/L2-1); %% 8000/27 TipodegrficaButtonGroup
             
         data1=[data1 "Iniciando simulación..."];
         app.Eventos.Value=data1;
         app.Eventos_2.Value=data1;


            switch app.TipodegrficaButtonGroupRe.SelectedObject.Text
                case 'Entropía'
                    plot(app.Entrop, t(1:L-1), EnH1r(1:L-1));
                    app.Entrop.Title.String='Entropía de nivel de líquido medido en tanque 1';
                    app.Entrop.XLabel.String='Tiempo [s]';
                    app.Entrop.YLabel.String='Desorden';
            
                case 'Hr vs He'
                    scatter(app.Entrop,Nm1,Ne1,'r');
                    app.Entrop.Title.String='Nivel medido vs Nivel estimado en el tanque 1';
                    app.Entrop.XLabel.String='Nivel medido';
                    app.Entrop.YLabel.String='Nivel estimado';
            
            
                case 'Histograma'
                    histogram(app.Entrop,Nm1,30);
                    app.Entrop.Title.String='Histograma de nivel medido en el tanque 1';
                    app.Entrop.XLabel.String='Nivel';
                    app.Entrop.YLabel.String='Distribución de datos';
            end
            
             if max(EnH1r(1:L-1))<0.5
                    data1=[data1 "Falla en el sensor del tanque 1 y falla en la tuberia de interconexion "];
                    app.Eventos.Value=data1;
                    %app.Eventos_2.Value=data;
                    else
                    data1=[data1 "Lectura normal en sensor del tanque 1"];
                    app.Eventos.Value=data1;
                    %app.Eventos_2.Value=data;
             end            
            
            switch app.TipodegrficaButtonGroupRe_2.SelectedObject.Text
                case 'Entropía2'
                    plot(app.Entrop_2, t2(1:L2-1), EnH2r(1:L2-1));
                    app.Entrop_2.Title.String='Entropía de nivel de líquido medido en tanque 2';
                    app.Entrop_2.XLabel.String='Tiempo [s]';
                    app.Entrop_2.YLabel.String='Desorden';
            
                case 'Hr vs He 2'
                    scatter(app.Entrop_2,Nm2,Ne2,'r');
                    app.Entrop_2.Title.String='Nivel medido vs Nivel estimado en el tanque 2';
                    app.Entrop_2.XLabel.String='Nivel medido';
                    app.Entrop_2.YLabel.String='Nivel estimado';
            
            
                case 'Histograma2'
                    histogram(app.Entrop_2,Nm2,30);
                    app.Entrop_2.Title.String='Histograma de nivel medido en el tanque 2';
                    app.Entrop_2.XLabel.String='Nivel';
                    app.Entrop_2.YLabel.String='Distribución de datos';
            end
             if max(EnH2r(1:L2-1))<0.282
                        data1=[data1 "Falla en el sensor del tanque 2 y falla en la tuberia de interconexion "];
                        %app.Eventos.Value=data1;
                        app.Eventos_2.Value=data1;
                    else
                        data1=[data1 "Lectura normal en sensor del tanque 2"];
                        %app.Eventos.Value=data1; 
                        app.Eventos_2.Value=data1;
             end
 
         
         data1=[data1 "Simulación terminada."];
         app.Eventos.Value=data1;
         app.Eventos_2.Value=data1;
           
         
        end

        % Button pushed function: InicioButton3
        function InicioButton3Pushed(app, event)
         assignin('base','A1',95.52);
            assignin('base','A2',64);
            assignin('base','k1',0.958);
            assignin('base','k2',1.3635);
  
         assignin('base','perturbacion2',app.perturbaE2.Value);
         assignin('base','errortub2',app.ErrorTub2.Value);
         
         simout=sim('Analisis_dos_tanques.slx','StopTime',num2str(8000));
         
%          for i=1:124
%              app.nm1.Value= simout.NivelM1.Data(i);
%              app.nm2.Value= simout.NivelM2.Data(i);
%              app.ne1.Value= simout.NivelE1.Data(i);
%              app.ne2.Value= simout.NivelE2.Data(i);
%              
%             drawnow
%          end
         %pause(10);
         %app.Gauge.Value=simout.NivelM1.Data;
         app.nm1.Value=simout.NivelM1.Data(124);
         app.nm2.Value=simout.NivelM2.Data(124);
         app.ne1.Value=simout.NivelE1.Data(124);
         app.ne2.Value=simout.NivelE2.Data(124);
         
         if simout.fallanivel.Data(124)==1
             app.FalladesensordenivelLamp.Enable = 'on';
         else 
             app.FalladesensordenivelLamp.Enable = 'off';
         end
         
         if simout.fallatuberia.Data(124)==1
             app.FalladetuberiaLamp.Enable = 'on';
         else
             app.FalladetuberiaLamp.Enable = 'off';
         end
        end

        % Button pushed function: inicioButton4
        function inicioButton4Pushed(app, event)
              simout=sim('Clustering2Tanques.slx','StopTime',num2str(8000));
              %simout=sim('Analisis_dos_tanques.slx','StopTime',num2str(8000));
             
              tiem=simout.Tanque1.time;
              plot(app.s1, simout.M1.Time, simout.M1.Data);
              plot(app.s3, simout.M2.Time, simout.M2.Data);
              Data=[simout.M1.Data];
              Data2=[simout.M2.Data];
              X1 = Data;
              XX = Data2;
              %----------------
             % X1 = XX;
              f=fittype('a*x+b');
            pa=0.6157;
            pb=97.47;
            %pa=0.0002365;
            %pb=2.306;
            for cont=1:100:16000 
             fit1=fit(tiem(cont:cont+99),X1(cont:cont+99),f,'StartPoint',[pa,pb]);
               pa=fit1.a;
               pb=fit1.b;
            for i1=0:1:99
               s=i1*.5+(cont*.5);
              polinom(cont+i1)=pa*s+pb;
           end
        
         plot(app.s2, tiem(1:cont+99),polinom(1:cont+99));
        end
          %---------------------------------------------------------
          
        X2 = XX;
%figure
        f=fittype('a*x+b');
        % pa=0.6157;
        % pb=97.47;
        pa=0.0002365;
        pb=2.306;
        for cont=1:100:16000
        fit1=fit(tiem(cont:cont+99),X2(cont:cont+99),f,'StartPoint',[pa,pb]);
        pa=fit1.a;
        pb=fit1.b;
    
    for i1=0:1:99
        s=i1*.5+(cont*.5);
        polinom2(cont+i1)=pa*s+pb;
    end
    plot(app.s4, tiem(1:cont+99),polinom2(1:cont+99));
   end


        end

        % Button pushed function: Entrenar
        function EntrenarButtonPushed(app, event)
        assignin('base','A1',95.52);
        assignin('base','A2',64);
        assignin('base','k1',0.958);
        assignin('base','k2',1.3635);
        assignin('base','Nivel2_err',0);
        assignin('base','Tuberia_err',0);
     
        switch app.SelecciondeperturbacionButtonGroup.SelectedObject.Text
                case 'Error 5 m'
                  assignin('base','Nivel_err',5);
               case 'Error 10 m'
                   assignin('base','Nivel_err',10);
                   
                case 'Error 15 m'
                    assignin('base','Nivel_err',15);
                
        end
        
          simout=sim('Clustering2Tanques.slx','StopTime',num2str(8000));
          
          tiem=simout.Tanque1.time;
          Data=[simout.M1.Data];
          X = Data;
          XX = Data;

    
          f=fittype('a*x+b');
          pa=0.6157;
          pb=97.47;
           %pa=0.0002365;
           %pb=2.306;
           for cont=1:100:16000 
             fit1=fit(tiem(cont:cont+99),X(cont:cont+99),f,'StartPoint',[pa,pb]);
             pa=fit1.a;
             pb=fit1.b;
           for i1=0:1:99
              s=i1*.5+(cont*.5);
              polinom(cont+i1)=pa*s+pb;
          end
       end             
           
%    ----DATOS DE ENTRENAMIENTO

       q=length(polinom);
       q2=length(XX);
       error=q2-q;
       X(1:error,1)=0;
       X(error+1:q2,1)=polinom;
%        -----------Pruebas de tamaño-------------------------
%                assignin('base','Tama_XX',length(XX));
%                assignin('base','Tama_X',length(X_w));
%                assignin('base','Tama_poli',length(polinom));
%                assignin('base','XX',XX);assignin('base','X',X_w);
% -------------------------------------------------------------------------
       Z_1 = linkage(X,'ward','euclidean');
       c_1 = cluster(Z_1,'maxclust',7);
       P_1=X;%EL FILTRADO
       E_1=XX;%LOS DATOS NO FILTRADOS
       grupo_1=c_1;
       
        assignin('base','C_11',grupo_1); %C_11
        assignin('base','E_11',E_1); %GRUPO_11
         assignin('base','clase_11',clase1_1);
       
       [clase1_1,error_1]=classify(P_1,E_1,grupo_1,'diaglinear');

         
       plot(app.Discriminant1, simout.M1.Time, X);
       figure(1)
       gscatter(simout.Tanque1.time,X,clase1_1)
%          plot(app.Discriminant1, tiem(1:cont+99),polinom(1:cont+99));
%      -----------------------------Entrenamiento para muestras del sensor 2---------

       Data2=[simout.M2.Data];
       Y=Data2;
       YY=Data2;
       f=fittype('a*x+b');
       pa=0.6157;
       pb=97.47;
       %pa=0.0002365;
       %pb=2.306;
       for cont=1:100:16000 
         fit1=fit(tiem(cont:cont+99),Y(cont:cont+99),f,'StartPoint',[pa,pb]);
         pa=fit1.a;
         pb=fit1.b;
         for i1=0:1:99
            s=i1*.5+(cont*.5);
            polinom1(cont+i1)=pa*s+pb;
          end
       end             
       
%       Y= polinom1;
%        q=length(polinom);
%        q2=length(XX);
%        error=q2-q;
%        X(1:error,1)=0;
%        X(error+1:q2,1)=polinom;
       q1=length(polinom1);
       q22=length(YY);
       error1=q22-q1;
       Y(1:error1,1)=0;
       Y(error1+1:q22,1)=polinom1;
       
%-------------- Entrenamiento2----------------------
       
       Z1 = linkage(Y,'ward','euclidean');
       c1 = cluster(Z1,'maxclust',7);
       P1_2=Y;
       E1_2=YY;
       grupo1_2=c1;
       [clase1_2,error1_2]=classify(P1_2,E1_2,grupo1_2,'diaglinear');
       
       assignin('base','C_12',grupo1_2);
       assignin('base','E_12',E1_2);
        assignin('base','clase_12',clase1_2);
%        plot(app.Discriminant2, gscatter(simout.Tanque2.time,Y,clase1) );
%   plot(app.Discriminant2, simout.M1.Time, Y);
        end

        % Button pushed function: Entrenar2
        function Entrenar2ButtonPushed(app, event)
        assignin('base','A1',95.52);
        assignin('base','A2',64);
        assignin('base','k1',0.958);
        assignin('base','k2',1.3635);
        assignin('base','Nivel_err',0);
        assignin('base','Tuberia_err',0);


        switch app.Selecciondeperturbacion2ButtonGroup.SelectedObject.Text
                case 'Error2_1'
                  assignin('base','Nivel2_err',1);
                case 'Error2_3'
                   assignin('base','Nivel2_err',3);
                case 'Error2_5'
                    assignin('base','Nivel2_err',5);
                
        end
            
          simout=sim('Clustering2Tanques.slx','StopTime',num2str(8000));
          tiem=simout.Tanque1.time;
          Data=[simout.M1.Data];
          X = Data;
          XX = Data;

          f=fittype('a*x+b');
          pa=0.6157;
          pb=97.47;
          %pa=0.0002365;
          %pb=2.306;
          for cont=1:100:16000 
             fit1=fit(tiem(cont:cont+99),X(cont:cont+99),f,'StartPoint',[pa,pb]);
             pa=fit1.a;
             pb=fit1.b;
           for i1=0:1:99
              s=i1*.5+(cont*.5);
              polinom(cont+i1)=pa*s+pb;
          end
       end             
           
%    ----DATOS DE ENTRENAMIENTO

       q=length(polinom);
       q2=length(XX);
       error=q2-q;
       X(1:error,1)=0;
       X(error+1:q2,1)=polinom;
%        -----------Pruebas de tamaño-------------------------
%                assignin('base','Tama_XX',length(XX));
%                assignin('base','Tama_X',length(X_w));
%                assignin('base','Tama_poli',length(polinom));
%                assignin('base','XX',XX);assignin('base','X',X_w);
% -------------------------------------------------------------------------
       Z_1 = linkage(X,'ward','euclidean');
       c_1 = cluster(Z_1,'maxclust',7);
       P2_1=X;
       E2_1=XX;
       grupo2_1=c_1;
            
       [clase2_1,error2_1]=classify(P2_1,E2_1,grupo2_1,'diaglinear');
      
       assignin('base','C_21',grupo2_1);
       assignin('base','E_21',E2_1);

 plot(app.Discriminant2, simout.M1.Time, X);
figure(2)
gscatter(simout.Tanque2.time,X,clase2_1)
%      -----------------------------Entrenamiento para muestras del sensor 2---------

       Data2=[simout.M2.Data];
       Y=Data2;
       YY=Data2;
       f=fittype('a*x+b');
       pa=0.6157;
       pb=97.47;
       %pa=0.0002365;
       %pb=2.306;
       for cont=1:100:16000 
         fit1=fit(tiem(cont:cont+99),Y(cont:cont+99),f,'StartPoint',[pa,pb]);
         pa=fit1.a;
         pb=fit1.b;
         for i1=0:1:99
            s=i1*.5+(cont*.5);
            polinom1(cont+i1)=pa*s+pb;
          end
       end             
       
%       Y= polinom1;
%        q=length(polinom);
%        q2=length(XX);
%        error=q2-q;
%        X(1:error,1)=0;
%        X(error+1:q2,1)=polinom;
       q1=length(polinom1);
       q22=length(YY);
       error1=q22-q1;
       Y(1:error1,1)=0;
       Y(error1+1:q22,1)=polinom1;
       
%-------------- Entrenamiento2----------------------
       
       Z1 = linkage(Y,'ward','euclidean');
       c1 = cluster(Z1,'maxclust',7);
       P2_2=Y;
       E2_2=YY;
       grupo2_2=c1;
       [clase2_2,error2_2]=classify(P2_2,E2_2,grupo2_2,'diaglinear');  
       
       assignin('base','C_22',grupo2_2);
       assignin('base','E_22',E2_2);
       assignin('base','clase_22',clase2_2);

%   plot(app.Discriminant2, simout.M1.Time, Y);
   figure(3)
    gscatter(simout.Tanque2.time,Y,clase2_2)
        end

        % Button pushed function: Entrenar3
        function Entrenar3ButtonPushed(app, event)
        assignin('base','A1',95.52);
        assignin('base','A2',64);
        assignin('base','k1',0.958);
        assignin('base','k2',1.3635);
        assignin('base','Nivel_err',0);
        assignin('base','Nivel2_err',0);
         

        switch app.Selecciondeperturbacion3ButtonGroup.SelectedObject.Text
                case 'Error3_-1'
                  assignin('base','Tuberia_err',-1);
                case 'Error3_-3'
                   assignin('base','Tuberia_err',-3);
                case 'Error3_-5'
                    assignin('base','Tuberia_err',-5);
                
        end
            
          simout=sim('Clustering2Tanques.slx','StopTime',num2str(8000));
          tiem=simout.Tanque1.time;
          Data=[simout.M1.Data];
          X = Data;
          XX = Data;

          f=fittype('a*x+b');
          pa=0.6157;
          pb=97.47;
          %pa=0.0002365;
          %pb=2.306;
          for cont=1:100:16000 
             fit1=fit(tiem(cont:cont+99),X(cont:cont+99),f,'StartPoint',[pa,pb]);
             pa=fit1.a;
             pb=fit1.b;
           for i1=0:1:99
              s=i1*.5+(cont*.5);
              polinom(cont+i1)=pa*s+pb;
          end
       end             
           
%    ----DATOS DE ENTRENAMIENTO

       q=length(polinom);
       q2=length(XX);
       error=q2-q;
       X(1:error,1)=0;
       X(error+1:q2,1)=polinom;
%        -----------Pruebas de tamaño-------------------------
%                assignin('base','Tama_XX',length(XX));
%                assignin('base','Tama_X',length(X_w));
%                assignin('base','Tama_poli',length(polinom));
%                assignin('base','XX',XX);assignin('base','X',X_w);
% -------------------------------------------------------------------------
       Z_1 = linkage(X,'ward','euclidean');
       c_1 = cluster(Z_1,'maxclust',7);
       P3_1=X;
       E3_1=XX;
       grupo3_1=c_1;
       [clase3_1,error3_1]=classify(P3_1,E3_1,grupo3_1,'diaglinear');
       
       assignin('base','C_31',grupo3_1);
       assignin('base','E_31',E3_1);
       assignin('base','clase_31',clase3_1);
 plot(app.Discriminant3, simout.M1.Time, X);
 figure(4)
 gscatter(simout.Tanque2.time,X,clase3_1)
%      -----------------------------Entrenamiento para muestras del sensor 2---------

       Data2=[simout.M2.Data];
       Y=Data2;
       YY=Data2;
       f=fittype('a*x+b');
       pa=0.6157;
       pb=97.47;
       %pa=0.0002365;
       %pb=2.306;
       for cont=1:100:16000 
         fit1=fit(tiem(cont:cont+99),Y(cont:cont+99),f,'StartPoint',[pa,pb]);
         pa=fit1.a;
         pb=fit1.b;
         for i1=0:1:99
            s=i1*.5+(cont*.5);
            polinom1(cont+i1)=pa*s+pb;
          end
       end             
       
%       Y= polinom1;
%        q=length(polinom);
%        q2=length(XX);
%        error=q2-q;
%        X(1:error,1)=0;
%        X(error+1:q2,1)=polinom;
       q1=length(polinom1);
       q22=length(YY);
       error1=q22-q1;
       Y(1:error1,1)=0;
       Y(error1+1:q22,1)=polinom1;
       
%-------------- Entrenamiento2----------------------
       
       Z1 = linkage(Y,'ward','euclidean');
       c1 = cluster(Z1,'maxclust',7);
       P3_1=Y;
       E3_1=YY;
       grupo1=c1;
       [clase3_2,error3_1]=classify(P3_1,E3_1,grupo3_1,'diaglinear');  
      
       assignin('base','C_32',grupo3_1);
       assignin('base','E_32',E3_1);
       assignin('base','clase_32',clase3_2);

%   plot(app.Discriminant3, gscatter(simout.Tanque2.time,Y,clase1) );
%   plot(app.Discriminant3, simout.M1.Time, Y);
        end

        % Button pushed function: Detectar
        function DetectarButtonPushed(app, event)
        assignin('base','A1',95.52);
        assignin('base','A2',64);
        assignin('base','k1',0.958);
        assignin('base','k2',1.3635);
        assignin('base','Nivel_err',0);
        assignin('base','Nivel2_err',0);
        assignin('base','Tuberia_err',0);
        
         switch app.Selecciondeperturbacion4ButtonGroup.SelectedObject.Text
              case 'Falla sensor de altura 1'
                   assignin('base','Nivel_err',app.get_Valor.Value);
                
             case 'Falla sensor de altura 2'
                   assignin('base','Nivel2_err',app.get_Valor.Value);
                
             case 'Falla tuberia de conexion'
                  assignin('base','Tuberia_err',app.get_Valor.Value);
        end
        
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 % Detteccion del sensor 1
    simout=sim('Clustering2Tanques.slx','StopTime',num2str(8000));
    tiem=simout.Tanque1.time;
    Data=[simout.M1.Data];
    X = Data;
    XX = Data;

    f=fittype('a*x+b');
    pa=0.6157;
    pb=97.47;
     %pa=0.0002365;
     %pb=2.306;
     for cont=1:100:16000 
          fit1=fit(tiem(cont:cont+99),X(cont:cont+99),f,'StartPoint',[pa,pb]);
          pa=fit1.a;
          pb=fit1.b;
       for i1=0:1:99
          s=i1*.5+(cont*.5);
          polinom(cont+i1)=pa*s+pb;
       end
     end         
     
             
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
 % Detteccion del sensor 2
  Data2=[simout.M2.Data];
       Y=Data2;
       YY=Data2;
       f=fittype('a*x+b');
       pa=0.6157;
       pb=97.47;
       %pa=0.0002365;
       %pb=2.306;
       for cont=1:100:16000 
         fit1=fit(tiem(cont:cont+99),Y(cont:cont+99),f,'StartPoint',[pa,pb]);
         pa=fit1.a;
         pb=fit1.b;
         for i1=0:1:99
            s=i1*.5+(cont*.5);
            polinom1(cont+i1)=pa*s+pb;
          end
       end            
     
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %funcion discriminante sensor 1
       q1_1=length(polinom);
       q2_1=length(XX);
       error1_1=q2_1-q1_1;
       X(1:error1_1,1)=0;
       X(error1_1+1:q2_1,1)=polinom;
      q1_2=length(polinom1);
     q2_2=length(YY);
     error2_1=q2_2-q1_2;
     Y(1:error2_1,1)=0;
     Y(error2_1+1:q2_1,1)=polinom1;
     
     
     b1=0;
     P1_1=X;
     E1_1=evalin('base','E_11')
     grupo1_1=evalin('base','C_11')
     [clase1_1,error1_1]=classify(P1_1,E1_1,grupo1_1,'diaglinear');  
          figure(5)
          gscatter(simout.Tanque2.time,X,clase1_1)
     P1_2=Y;
     E1_2=evalin('base','E_12')
     grupo1_2=evalin('base','C_12')
     [clase1_2,error1_1]=classify(P1_2,E1_2,grupo1_2,'diaglinear'); 
     figure(6)
     gscatter(simout.Tanque2.time,Y,clase1_2)
          
      k=0;
      k=find(clase1_1==5);
       if k~=0;
         data1=["Falla en el sensor del tanque 1 ..."];
                    app.Sensor1.Enable = 'off';
                    b1=1;
              
       else
           data1=["Sensor de altura de tanque 1 normal ..."];
                     app.Sensor1.Enable = 'on';
       
       end


  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %funcion discriminante tubería
    b2=0;
     q1_3=length(polinom);
     q2_3=length(XX);
     error3_1=q2_3-q1_3;
     X(1:error3_1,1)=0;
     X(error3_1+1:q2_3,1)=polinom;
     P3_1=X;
     E3_1=evalin('base','E_31')
     grupo3_1=evalin('base','C_31')
     [clase3_1,error3_1]=classify(P3_1,E3_1,grupo3_1,'diaglinear'); 
     figure(7)
   gscatter(simout.Tanque2.time,X,clase3_1)
     P3_2=X;
     E3_2=evalin('base','E_32')
     grupo3_2=evalin('base','C_32')
     [clase3_2,error3_2]=classify(P3_2,E3_2,grupo3_2,'diaglinear'); 
     figure(8)
   gscatter(simout.Tanque2.time,Y,clase3_2)
      k=0;
      k=find(clase3_1==5);
       if k~=0;
         data3=[ "Falla en la tubería de conexión... "];
                      app.Sensor3.Enable = 'off';
              b2=1;
       else
           data3=[ "Tubería normal...  "];
                    
                    app.Sensor1.Enable = 'on';
       
       end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 %funcion discriminante sensor 2
     q1_2=length(polinom1);
     q2_2=length(YY);
     error2_1=q2_2-q1_2;
     Y(1:error2_1,1)=0;
     Y(error2_1+1:q2_1,1)=polinom1;
     P2_1=Y;
     E2_1=evalin('base','E_21')
     grupo2_1=evalin('base','C_21')
     [clase2_1,error2_1]=classify(P2_1,E2_1,grupo2_1,'diaglinear');  
      k=0;
      k=find(clase2_1==1);
      if k~=0;
      if(b1==1) || (b2==1) 
                data2=[ "Sensor de altura de tanque 2 normal ..."];                  
                app.Sensor2.Enable = 'on';
      else
          data2=[ "Falla en el sensor del tanque 2 ..."];
         app.Sensor2.Enable = 'off'      
      end
      else
         data2=[ "Sensor de altura de tanque 2 normal ..."];                     
         app.Sensor2.Enable = 'on';
     end    
%        if k~=0;
%          data2=[ "Falla en el sensor del tanque 2 ..."];
%                       app.Sensor2.Enable = 'off';
%               
%        else
%            data2=[ "Sensor de altura de tanque 2 normal ..."];
%                     
%                     app.Sensor2.Enable = 'on';
%        
%        end
       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           app.EventosD.Value=data1+data2+data3;
        
        assignin('base','b1',b1);
        assignin('base','b1',b2);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Anlisis1Tab = uitab(app.TabGroup2);
            app.Anlisis1Tab.Title = 'Análisis1';
            app.TabGroup = uitabgroup(app.Anlisis1Tab);
            app.H1rTab = uitab(app.TabGroup);
            app.H1rTab.Title = 'H1r';
            app.H1r = uiaxes(app.H1rTab);
            title(app.H1r, 'Nivel de líquido medido tanque 1')
            xlabel(app.H1r, 'Tiempo')
            ylabel(app.H1r, 'Nivel')
            app.H2rTab = uitab(app.TabGroup);
            app.H2rTab.Title = 'H2r';
            app.H2r = uiaxes(app.H2rTab);
            title(app.H2r, 'Nivel de líquido medido tanque 2')
            xlabel(app.H2r, 'Tiempo')
            ylabel(app.H2r, 'Nivel')
            app.H1eTab = uitab(app.TabGroup);
            app.H1eTab.Title = 'H1e';
            app.H1e = uiaxes(app.H1eTab);
            title(app.H1e, 'Nivel de líquido estimado tanque 1')
            xlabel(app.H1e, 'Tiempo')
            ylabel(app.H1e, 'Nivel')
            app.H2eTab = uitab(app.TabGroup);
            app.H2eTab.Title = 'H2e';
            app.H2e = uiaxes(app.H2eTab);
            title(app.H2e, 'Nivel de líquido estimado tanque 2')
            xlabel(app.H2e, 'Tiempo')
            ylabel(app.H2e, 'Nivel')
            app.EventosTextAreaLabel = uilabel(app.Anlisis1Tab);
            app.EventosTextAreaLabel.Text = 'Eventos';
            app.Status1 = uitextarea(app.Anlisis1Tab);
            app.Status1.ValueChangedFcn = createCallbackFcn(app, @Status1ValueChanged, true);
            app.INICIOButton = uibutton(app.Anlisis1Tab, 'push');
            app.INICIOButton.ButtonPushedFcn = createCallbackFcn(app, @INICIOButtonPushed, true);
            app.INICIOButton.Text = 'INICIO';
            app.Anlisis2Tab = uitab(app.TabGroup2);
            app.Anlisis2Tab.Title = 'Análisis 2';
            app.TabGroup3 = uitabgroup(app.Anlisis2Tab);
            app.Tanque1Tab = uitab(app.TabGroup3);
            app.Tanque1Tab.Title = 'Tanque 1';
            app.Entrop = uiaxes(app.Tanque1Tab);
            title(app.Entrop, '')
            xlabel(app.Entrop, 'Tiempo')
            ylabel(app.Entrop, '')
            app.TipodegrficaButtonGroupRe = uibuttongroup(app.Tanque1Tab);
            app.TipodegrficaButtonGroupRe.Title = 'Tipo de gráfica';
            app.EntropaButton = uiradiobutton(app.TipodegrficaButtonGroupRe);
            app.EntropaButton.Text = 'Entropía';
            app.EntropaButton.Value = true;
            app.HrvsHeButton = uiradiobutton(app.TipodegrficaButtonGroupRe);
            app.HrvsHeButton.Text = 'Hr vs He';
            app.HistogramaButton = uiradiobutton(app.TipodegrficaButtonGroupRe);
            app.HistogramaButton.Text = 'Histograma';
            app.EventosTextAreaLabel_2 = uilabel(app.Tanque1Tab);
            app.EventosTextAreaLabel_2.Text = 'Eventos';
            app.Eventos = uitextarea(app.Tanque1Tab);
            app.Tanque2Tab = uitab(app.TabGroup3);
            app.Tanque2Tab.Title = 'Tanque 2';
            app.Entrop_2 = uiaxes(app.Tanque2Tab);
            title(app.Entrop_2, '')
            xlabel(app.Entrop_2, 'Tiempo')
            ylabel(app.Entrop_2, '')
            app.TipodegrficaButtonGroupRe_2 = uibuttongroup(app.Tanque2Tab);
            app.TipodegrficaButtonGroupRe_2.Title = 'Tipo de gráfica';
            app.Entropa2Button = uiradiobutton(app.TipodegrficaButtonGroupRe_2);
            app.Entropa2Button.Text = 'Entropía2';
            app.Entropa2Button.Value = true;
            app.HrvsHe2Button = uiradiobutton(app.TipodegrficaButtonGroupRe_2);
            app.HrvsHe2Button.Text = 'Hr vs He 2';
            app.Histograma2Button = uiradiobutton(app.TipodegrficaButtonGroupRe_2);
            app.Histograma2Button.Text = 'Histograma2';
            app.EventosTextAreaLabel_3 = uilabel(app.Tanque2Tab);
            app.EventosTextAreaLabel_3.Text = 'Eventos';
            app.Eventos_2 = uitextarea(app.Tanque2Tab);
            app.InicioButton = uibutton(app.Anlisis2Tab, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.PerturbacindenivelEditFieldLabel = uilabel(app.Anlisis2Tab);
            app.PerturbacindenivelEditFieldLabel.Text = 'Perturbación de nivel';
            app.perturbaE = uieditfield(app.Anlisis2Tab, 'numeric');
            app.ErrorentuberaEditFieldLabel = uilabel(app.Anlisis2Tab);
            app.ErrorentuberaEditFieldLabel.Text = 'Error en tubería';
            app.ErrorTub = uieditfield(app.Anlisis2Tab, 'numeric');
            app.Anlisis3Tab = uitab(app.TabGroup2);
            app.Anlisis3Tab.Title = 'Análisis 3';
            app.ErrorentuberaEditFieldLabel_2 = uilabel(app.Anlisis3Tab);
            app.ErrorentuberaEditFieldLabel_2.Text = 'Error en tubería';
            app.ErrorTub2 = uieditfield(app.Anlisis3Tab, 'numeric');
            app.PerturbacindenivelEditFieldLabel_2 = uilabel(app.Anlisis3Tab);
            app.PerturbacindenivelEditFieldLabel_2.Text = 'Perturbación de nivel';
            app.perturbaE2 = uieditfield(app.Anlisis3Tab, 'numeric');
            app.NivelmedidoLabel = uilabel(app.Anlisis3Tab);
            app.NivelmedidoLabel.Text = 'Nivel medido';
            app.NivelmedidoLabel_2 = uilabel(app.Anlisis3Tab);
            app.NivelmedidoLabel_2.Text = 'Nivel medido';
            app.NivelestimadoLabel = uilabel(app.Anlisis3Tab);
            app.NivelestimadoLabel.Text = 'Nivel estimado';
            app.NivelestimadoLabel_2 = uilabel(app.Anlisis3Tab);
            app.NivelestimadoLabel_2.Text = 'Nivel estimado';
            app.nm1 = uieditfield(app.Anlisis3Tab, 'numeric');
            app.ne1 = uieditfield(app.Anlisis3Tab, 'numeric');
            app.nm2 = uieditfield(app.Anlisis3Tab, 'numeric');
            app.ne2 = uieditfield(app.Anlisis3Tab, 'numeric');
            app.FalladesensordenivelLampLabel = uilabel(app.Anlisis3Tab);
            app.FalladesensordenivelLampLabel.Text = 'Falla de sensor de nivel';
            app.FalladesensordenivelLamp = uilamp(app.Anlisis3Tab);
            app.FalladetuberiaLampLabel = uilabel(app.Anlisis3Tab);
            app.FalladetuberiaLampLabel.Text = 'Falla de tuberia';
            app.FalladetuberiaLamp = uilamp(app.Anlisis3Tab);
            app.InicioButton3 = uibutton(app.Anlisis3Tab, 'push');
            app.InicioButton3.ButtonPushedFcn = createCallbackFcn(app, @InicioButton3Pushed, true);
            app.InicioButton3.Text = 'Inicio';
            app.modeladoTab = uitab(app.TabGroup2);
            app.modeladoTab.Title = 'modelado';
            app.inicioButton4 = uibutton(app.modeladoTab, 'push');
            app.inicioButton4.ButtonPushedFcn = createCallbackFcn(app, @inicioButton4Pushed, true);
            app.inicioButton4.Text = 'inicio';
            app.TabGroup4 = uitabgroup(app.modeladoTab);
            app.sensoraltura1Tab = uitab(app.TabGroup4);
            app.sensoraltura1Tab.Title = 'sensor altura 1';
            app.s1 = uiaxes(app.sensoraltura1Tab);
            title(app.s1, 's1')
            xlabel(app.s1, 'X')
            ylabel(app.s1, 'Y')
            app.s2 = uiaxes(app.sensoraltura1Tab);
            title(app.s2, 's2')
            xlabel(app.s2, 'X')
            ylabel(app.s2, 'Y')
            app.sensoraltura2Tab = uitab(app.TabGroup4);
            app.sensoraltura2Tab.Title = 'sensor altura 2';
            app.s3 = uiaxes(app.sensoraltura2Tab);
            title(app.s3, 'Title')
            xlabel(app.s3, 'X')
            ylabel(app.s3, 'Y')
            app.s4 = uiaxes(app.sensoraltura2Tab);
            title(app.s4, 'Title')
            xlabel(app.s4, 'X')
            ylabel(app.s4, 'Y')
            app.EntrenamientoTab = uitab(app.TabGroup2);
            app.EntrenamientoTab.Title = 'Entrenamiento';
            app.TabGroup5 = uitabgroup(app.EntrenamientoTab);
            app.fallasensordenivel1Tab = uitab(app.TabGroup5);
            app.fallasensordenivel1Tab.Title = 'falla sensor de nivel 1';
            app.Entrenar = uibutton(app.fallasensordenivel1Tab, 'push');
            app.Entrenar.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.Entrenar.Text = 'Entrenar';
            app.SelecciondeperturbacionButtonGroup = uibuttongroup(app.fallasensordenivel1Tab);
            app.SelecciondeperturbacionButtonGroup.Title = 'Seleccion de perturbacion';
            app.Error_5 = uiradiobutton(app.SelecciondeperturbacionButtonGroup);
            app.Error_5.Text = 'Error 5 m';
            app.Error_5.Value = true;
            app.Error_10 = uiradiobutton(app.SelecciondeperturbacionButtonGroup);
            app.Error_10.Text = 'Error 10 m';
            app.Error_15 = uiradiobutton(app.SelecciondeperturbacionButtonGroup);
            app.Error_15.Text = 'Error 15 m';
            app.Discriminant1 = uiaxes(app.fallasensordenivel1Tab);
            title(app.Discriminant1, 'Title')
            xlabel(app.Discriminant1, 'X')
            ylabel(app.Discriminant1, 'Y')
            app.fallasensornivel2Tab = uitab(app.TabGroup5);
            app.fallasensornivel2Tab.Title = 'falla sensor nivel 2';
            app.Entrenar2 = uibutton(app.fallasensornivel2Tab, 'push');
            app.Entrenar2.ButtonPushedFcn = createCallbackFcn(app, @Entrenar2ButtonPushed, true);
            app.Entrenar2.Text = 'Entrenar2';
            app.Selecciondeperturbacion2ButtonGroup = uibuttongroup(app.fallasensornivel2Tab);
            app.Selecciondeperturbacion2ButtonGroup.Title = 'Seleccion de perturbacion 2';
            app.Error2_1 = uiradiobutton(app.Selecciondeperturbacion2ButtonGroup);
            app.Error2_1.Text = 'Error2_1';
            app.Error2_1.Value = true;
            app.Error2_3 = uiradiobutton(app.Selecciondeperturbacion2ButtonGroup);
            app.Error2_3.Text = 'Error2_3';
            app.Error2_5 = uiradiobutton(app.Selecciondeperturbacion2ButtonGroup);
            app.Error2_5.Text = 'Error2_5';
            app.Discriminant2 = uiaxes(app.fallasensornivel2Tab);
            title(app.Discriminant2, 'Title')
            xlabel(app.Discriminant2, 'X')
            ylabel(app.Discriminant2, 'Y')
            app.PerturbaciondetuberiaTab = uitab(app.TabGroup5);
            app.PerturbaciondetuberiaTab.Title = 'Perturbacion de tuberia';
            app.Entrenar3 = uibutton(app.PerturbaciondetuberiaTab, 'push');
            app.Entrenar3.ButtonPushedFcn = createCallbackFcn(app, @Entrenar3ButtonPushed, true);
            app.Entrenar3.Text = 'Entrenar3';
            app.Selecciondeperturbacion3ButtonGroup = uibuttongroup(app.PerturbaciondetuberiaTab);
            app.Selecciondeperturbacion3ButtonGroup.Title = 'Seleccion de perturbacion 3';
            app.Error3_1N = uiradiobutton(app.Selecciondeperturbacion3ButtonGroup);
            app.Error3_1N.Text = 'Error3_-1';
            app.Error3_1N.Value = true;
            app.Error3_3N = uiradiobutton(app.Selecciondeperturbacion3ButtonGroup);
            app.Error3_3N.Text = 'Error3_-3';
            app.Error3_5N = uiradiobutton(app.Selecciondeperturbacion3ButtonGroup);
            app.Error3_5N.Text = 'Error3_-5';
            app.Discriminant3 = uiaxes(app.PerturbaciondetuberiaTab);
            title(app.Discriminant3, 'Title')
            xlabel(app.Discriminant3, 'X')
            ylabel(app.Discriminant3, 'Y')
            app.DeteccindefallasTab = uitab(app.TabGroup2);
            app.DeteccindefallasTab.Title = 'Detección de fallas';
            app.Detectar = uibutton(app.DeteccindefallasTab, 'push');
            app.Detectar.ButtonPushedFcn = createCallbackFcn(app, @DetectarButtonPushed, true);
            app.Detectar.Text = 'Detectar';
            app.Selecciondeperturbacion4ButtonGroup = uibuttongroup(app.DeteccindefallasTab);
            app.Selecciondeperturbacion4ButtonGroup.Title = 'Selecion de falla';
            app.Fallasensor1 = uiradiobutton(app.Selecciondeperturbacion4ButtonGroup);
            app.Fallasensor1.Text = 'Falla sensor de altura 1';
            app.Fallasensor1.Value = true;
            app.Fallasensor2 = uiradiobutton(app.Selecciondeperturbacion4ButtonGroup);
            app.Fallasensor2.Text = 'Falla sensor de altura 2';
            app.Fallasensor3 = uiradiobutton(app.Selecciondeperturbacion4ButtonGroup);
            app.Fallasensor3.Text = 'Falla tuberia de conexion';
            app.ValorfallaLabel = uilabel(app.DeteccindefallasTab);
            app.ValorfallaLabel.Text = 'Valor falla';
            app.get_Valor = uieditfield(app.DeteccindefallasTab, 'numeric');
            app.EventosTextAreaLabel_4 = uilabel(app.DeteccindefallasTab);
            app.EventosTextAreaLabel_4.Text = 'Eventos';
            app.EventosD = uitextarea(app.DeteccindefallasTab);
            app.Sensor1Label = uilabel(app.DeteccindefallasTab);
            app.Sensor1Label.Text = 'Sensor 1';
            app.Sensor1 = uilamp(app.DeteccindefallasTab);
            app.Sensor2Label = uilabel(app.DeteccindefallasTab);
            app.Sensor2Label.Text = 'Sensor 2';
            app.Sensor2 = uilamp(app.DeteccindefallasTab);
            app.Sensor3Label = uilabel(app.DeteccindefallasTab);
            app.Sensor3Label.Text = 'Sensor 3';
            app.Sensor3 = uilamp(app.DeteccindefallasTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCADA2Tanques

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end